classdef OceanOpticsSpectrometer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        ConnectSpectrometerButton      matlab.ui.control.Button
        StartliveviewButton            matlab.ui.control.Button
        IntegrationTimeEditFieldLabel  matlab.ui.control.Label
        IntegrationTimeEditField       matlab.ui.control.NumericEditField
        SaveSpectrumButton             matlab.ui.control.Button
        AveragesignalovermultiplestepsLabel  matlab.ui.control.Label
        Spinner                        matlab.ui.control.Spinner
        Label                          matlab.ui.control.Label
        StopButton                     matlab.ui.control.Button
        SetBGButton                    matlab.ui.control.Button
        ZeroBGButton                   matlab.ui.control.Button
        SetRefButton                   matlab.ui.control.Button
        NoRefButton                    matlab.ui.control.Button
        SaverawspectrumreferenceandbgButton  matlab.ui.control.Button
        RightPanel                     matlab.ui.container.Panel
        UIAxes                         matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        spectrometerObj % spectrometer object
        channelIndex=0
        spectrometerIndex = 0;
        timeObj
        rawSpectrum
        Wavelength
        BGSpec
        RefSpec
        N
        
        
    end
    
    methods (Access = private)
        
        function [wavelengths, spectralData]=getAverageSpectrum(app)
            wavelengths = invoke(app.spectrometerObj, 'getWavelengths', app.spectrometerIndex, app.channelIndex);
            spectralData=zeros(size(wavelengths));
            for ii=1:app.N
                spectralData = spectralData+ invoke(app.spectrometerObj, 'getSpectrum', app.spectrometerIndex)/app.N;
            end
        end
        
        function LiveSpectrum(app,~,~)
            rf=app.RefSpec;
            bg=app.BGSpec;
            [wavelengths, spectralData]=app.getAverageSpectrum();
            app.rawSpectrum=spectralData;
            app.Wavelength=wavelengths;
            plot(wavelengths, (spectralData-bg)./(rf-bg), 'Parent', app.UIAxes);

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.timeObj = timer(...
                'ExecutionMode', 'fixedRate', ...    % Run timer repeatedly
                'Period', 0.1, ...                     % Period is 0.1 second
                'BusyMode', 'queue',...              % Queue timer callbacks when busy
                'TimerFcn', @app.LiveSpectrum);    % Specify callback function
            app.N=app.Spinner.Value;
            xlim(app.UIAxes, [200, 1000])
            app.RefSpec=1;
            app.BGSpec=0;
        end

        % Button pushed function: StartliveviewButton
        function StartliveviewButtonPushed(app, event)
          start(app.timeObj);
        end

        % Value changed function: IntegrationTimeEditField
        function IntegrationTimeEditFieldValueChanged(app, event)
            intTime = app.IntegrationTimeEditField.Value;
            invoke(app.spectrometerObj, 'setIntegrationTime', app.spectrometerIndex, app.channelIndex, intTime);   
        end

        % Button pushed function: ConnectSpectrometerButton
        function ConnectSpectrometerButtonPushed(app, event)
            app.spectrometerObj = icdevice('OceanOptics_OmniDriver.mdd');
            connect(app.spectrometerObj);
            intTime = app.IntegrationTimeEditField.Value;
            invoke(app.spectrometerObj, 'setIntegrationTime', app.spectrometerIndex, app.channelIndex, intTime);   
            invoke(app.spectrometerObj, 'setCorrectForDetectorNonlinearity', app.spectrometerIndex, app.channelIndex, 1);
            % Enable correct for electrical dark.
            invoke(app.spectrometerObj, 'setCorrectForElectricalDark', app.spectrometerIndex, app.channelIndex, 1);
            spectrometerName = invoke(app.spectrometerObj, 'getName', app.spectrometerIndex);
            app.Label.Text =[spectrometerName, ' Connected'];

        end

        % Value changed function: Spinner
        function SpinnerValueChanged(app, event)
            app.N = app.Spinner.Value;
            
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            stop(app.timeObj)
        end

        % Button pushed function: SaveSpectrumButton
        function SaveSpectrumButtonPushed(app, event)
            fmts={'*.txt; *.mat';'*.csv'};
            [file,path, indx] = uiputfile(fmts);
            spectrum=(app.RawSpectrum-app.BGSpec)./(app.RefSpec-app.BGSpec);
            if indx==1
                save(fullfile(path, file), {'app.Wavelength','spectrum' }, '-ascii');
            elseif indx==2
                save(fullfile(path, file), {'app.Wavelength','spectrum' });
            elseif indx==3
                writematrix(fullfile(path, file), ['app.Wavelength','spectrum' ])
            end
        end

        % Button pushed function: SetBGButton
        function SetBGButtonPushed(app, event)
                app.BGSpec=app.rawSpectrum;
        end

        % Button pushed function: ZeroBGButton
        function ZeroBGButtonPushed(app, event)
            app.BGSpec=zeros(size(app.rawSpectrum));
        end

        % Button pushed function: SetRefButton
        function SetRefButtonPushed(app, event)
                app.RefSpec=app.rawSpectrum;   
        end

        % Button pushed function: NoRefButton
        function NoRefButtonPushed(app, event)
            app.RefSpec=ones(size(app.rawSpectrum));
        end

        % Button pushed function: 
        % SaverawspectrumreferenceandbgButton
        function SaverawspectrumreferenceandbgButtonPushed(app, event)
            fmts={'*.txt; *.mat';'*.csv'};
            [file,path, indx] = uiputfile(fmts);
            
            if indx==1
                save(fullfile(path, file), {'app.Wavelength','app.rawSpectrum', 'app.RefSpec', 'app.BGSpec' }, '-ascii');
            elseif indx==2
                save(fullfile(path, file), {'app.Wavelength','app.rawSpectrum', 'app.RefSpec', 'app.BGSpec'  });
            elseif indx==3
                writematrix(fullfile(path, file), ['app.Wavelength','app.rawSpectrum', 'app.RefSpec', 'app.BGSpec' ])
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {541, 541};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {210, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ConnectSpectrometerButton = uibutton(app.LeftPanel, 'push');
            app.ConnectSpectrometerButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectSpectrometerButtonPushed, true);
            app.ConnectSpectrometerButton.Text = 'Connect Spectrometer';
            app.StartliveviewButton = uibutton(app.LeftPanel, 'push');
            app.StartliveviewButton.ButtonPushedFcn = createCallbackFcn(app, @StartliveviewButtonPushed, true);
            app.StartliveviewButton.Text = 'Start live view';
            app.IntegrationTimeEditFieldLabel = uilabel(app.LeftPanel);
            app.IntegrationTimeEditFieldLabel.Text = 'Integration Time';
            app.IntegrationTimeEditField = uieditfield(app.LeftPanel, 'numeric');
            app.IntegrationTimeEditField.ValueChangedFcn = createCallbackFcn(app, @IntegrationTimeEditFieldValueChanged, true);
            app.IntegrationTimeEditField.Value = 50000;
            app.SaveSpectrumButton = uibutton(app.LeftPanel, 'push');
            app.SaveSpectrumButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSpectrumButtonPushed, true);
            app.SaveSpectrumButton.Text = 'Save Spectrum';
            app.AveragesignalovermultiplestepsLabel = uilabel(app.LeftPanel);
            app.AveragesignalovermultiplestepsLabel.Text = 'Average signal over multiple steps: ';
            app.Spinner = uispinner(app.LeftPanel);
            app.Spinner.RoundFractionalValues = 'on';
            app.Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.Spinner.Value = 1;
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = '';
            app.StopButton = uibutton(app.LeftPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.SetBGButton = uibutton(app.LeftPanel, 'push');
            app.SetBGButton.ButtonPushedFcn = createCallbackFcn(app, @SetBGButtonPushed, true);
            app.SetBGButton.Text = 'Set BG';
            app.ZeroBGButton = uibutton(app.LeftPanel, 'push');
            app.ZeroBGButton.ButtonPushedFcn = createCallbackFcn(app, @ZeroBGButtonPushed, true);
            app.ZeroBGButton.Text = 'Zero BG';
            app.SetRefButton = uibutton(app.LeftPanel, 'push');
            app.SetRefButton.ButtonPushedFcn = createCallbackFcn(app, @SetRefButtonPushed, true);
            app.SetRefButton.Text = 'Set Ref';
            app.NoRefButton = uibutton(app.LeftPanel, 'push');
            app.NoRefButton.ButtonPushedFcn = createCallbackFcn(app, @NoRefButtonPushed, true);
            app.NoRefButton.Text = 'No Ref';
            app.SaverawspectrumreferenceandbgButton = uibutton(app.LeftPanel, 'push');
            app.SaverawspectrumreferenceandbgButton.ButtonPushedFcn = createCallbackFcn(app, @SaverawspectrumreferenceandbgButtonPushed, true);
            app.SaverawspectrumreferenceandbgButton.Text = {'Save raw spectrum,'; 'reference, and bg'};
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'Wavelength (nm)')
            ylabel(app.UIAxes, 'Intensity (a.u)')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OceanOpticsSpectrometer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end